classdef DRISHTI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ViewCapacitySimulationButton  matlab.ui.control.Button
        StatusReadyLabel              matlab.ui.control.Label
        ExplainabilityEvidencePanel   matlab.ui.container.Panel
        Label_2                       matlab.ui.control.Label
        LesionReferenceImage          matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        ScreeningResultPanel          matlab.ui.container.Panel
        RecommendationLabel           matlab.ui.control.Label
        ImageQualityLabel             matlab.ui.control.Label
        ConfidenceLabel               matlab.ui.control.Label
        ClassificationLabel           matlab.ui.control.Label
        DRGradeLabel                  matlab.ui.control.Label
        RunDRISHTIScreeningButton     matlab.ui.control.Button
        SelectFundusImageButton       matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        Label                         matlab.ui.control.Label
        DRISHTILabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        SelectedImagePath = ''
        TrainedNet
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            S = load('../results/DRISHTI_trained_model.mat', 'net');
            app.TrainedNet = S.net;     
        end

        % Callback function
        function SelectFundusImageButtonPushed(app, event)
            
            
        end

        % Button pushed function: SelectFundusImageButton
        function SelectFundusImageButtonPushed2(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png', 'Fundus Images'});

            if isequal(file, 0)
                return;
            end

            app.SelectedImagePath = fullfile(path, file);
            app.Image.ImageSource = app.SelectedImagePath;
            app.StatusReadyLabel.Text = 'Status: Image Selected';
        end

        % Button pushed function: RunDRISHTIScreeningButton
        function RunDRISHTIScreeningButtonPushed(app, event)
            if isempty(app.SelectedImagePath)
                app.StatusReadyLabel.Text = 'Status: Select an image first';
                return;
            end

            app.StatusReadyLabel.Text = 'Status: Screening...';
            img = imread(app.SelectedImagePath);
            % Basic image quality analysis
            gray = im2gray(img);

            lapKernel = [0 1 0; 1 -4 1; 0 1 0];
            lap = imfilter(gray, lapKernel, 'replicate');
            focusScore = var(double(lap(:)));

            brightness = mean(gray(:));
            brightness = mean(gray(:)) / 255;
            darkRatio = mean(gray(:) < 0.05);

            % Quality thresholds
            focusOK = focusScore > 1;
            
            brightnessOK = brightness > 0.10 && brightness < 0.90;
            darkOK = darkRatio < 0.50;
            
            if focusOK && brightnessOK && darkOK
    app.ImageQualityLabel.Text = 'Image Quality: PASS';
else
    app.ImageQualityLabel.Text = 'Image Quality: FAIL';
    app.DRGradeLabel.Text = 'DR Grade: —';
    app.ClassificationLabel.Text = 'Classification: Ungradable';
    app.ConfidenceLabel.Text = 'Confidence: —';
    app.RecommendationLabel.Text = 'Recommendation: Recapture Image';
    app.StatusReadyLabel.Text = 'Status: Image Rejected';
    app.Image2.ImageSource = '';
    return;
end
            % Enhance the fundus image
            labImg = rgb2lab(img);
            L = labImg(:,:,1) / 100;

            L = adapthisteq(L);

            labImg(:,:,1) = L * 100;
            enhancedImg = lab2rgb(labImg);

            % Display enhanced image
            app.Image.ImageSource = enhancedImg;
           % AI-based DR prediction using trained ResNet-18
singleImageDS = imageDatastore({app.SelectedImagePath});
augSingleImage = augmentedImageDatastore([224 224 3], singleImageDS);

scores = minibatchpredict(app.TrainedNet, augSingleImage);

classNames = {'0','1','2','3','4'};
predictedLabel = scores2label(scores, classNames);

DR_grade = str2double(string(predictedLabel));
confidence = max(scores, [], 2);

app.StatusReadyLabel.Text = 'Status: Screening Complete';
            gradeNames = {
                'No DR'
                'Mild NPDR'
                'Moderate NPDR'
                'Severe NPDR'
                'Proliferative DR'
                };


            app.DRGradeLabel.Text = sprintf('DR Grade: %d', DR_grade);
            app.ClassificationLabel.Text = ['Classification: ' gradeNames{DR_grade + 1}];
            app.ConfidenceLabel.Text = sprintf('Confidence: %.0f%%', confidence * 100);
if DR_grade >= 2
    app.RecommendationLabel.Text = 'Recommendation: Refer';
else
    app.RecommendationLabel.Text = 'Recommendation: Routine Screening';
end

% Generate Grad-CAM explanation
try
    [~, predictedClassIndex] = max(scores, [], 2);

    inputForCAM = im2single(imresize(img, [224 224]));

    cam = gradCAM( ...
        app.TrainedNet, ...
        inputForCAM, ...
        predictedClassIndex);

    cam = gather(cam);
    cam = squeeze(cam);
    cam(~isfinite(cam)) = 0;

    camResized = imresize(cam, ...
        [size(enhancedImg,1) size(enhancedImg,2)]);

    camResized = mat2gray(camResized);

    heatmapRGB = ind2rgb( ...
        uint8(camResized * 255), jet(256));

    baseImage = im2double(enhancedImg);

    evidenceImage = 0.70 * baseImage + 0.30 * heatmapRGB;

    evidenceImage = min(max(evidenceImage, 0), 1);

    app.Image2.ImageSource = im2uint8(evidenceImage);

catch
    app.Image2.ImageSource = '';
end
        end

        % Button pushed function: ViewCapacitySimulationButton
        function ViewCapacitySimulationButtonPushed(app, event)
           open_system('../src/rural_capacity_model.slx');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'DRISHTI — Explainable Diabetic Retinopathy Screening';
            app.DRISHTILabel = uilabel(app.UIFigure);
            app.DRISHTILabel.Text = 'DRISHTI';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Explainable Diabetic Retinopathy Screening';
            app.Image = uiimage(app.UIFigure);
            app.SelectFundusImageButton = uibutton(app.UIFigure, 'push');
            app.SelectFundusImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFundusImageButtonPushed2, true);
            app.SelectFundusImageButton.Text = 'Select Fundus Image';
            app.RunDRISHTIScreeningButton = uibutton(app.UIFigure, 'push');
            app.RunDRISHTIScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @RunDRISHTIScreeningButtonPushed, true);
            app.RunDRISHTIScreeningButton.Text = 'Run DRISHTI Screening';
            app.ScreeningResultPanel = uipanel(app.UIFigure);
            app.ScreeningResultPanel.Title = 'Screening Result';
            app.DRGradeLabel = uilabel(app.ScreeningResultPanel);
            app.DRGradeLabel.Text = 'DR Grade: —';
            app.ClassificationLabel = uilabel(app.ScreeningResultPanel);
            app.ClassificationLabel.Text = 'Classification: —';
            app.ConfidenceLabel = uilabel(app.ScreeningResultPanel);
            app.ConfidenceLabel.Text = 'Confidence: —';
            app.ImageQualityLabel = uilabel(app.ScreeningResultPanel);
            app.ImageQualityLabel.Text = 'Image Quality:-';
            app.RecommendationLabel = uilabel(app.ScreeningResultPanel);
            app.RecommendationLabel.Text = 'Recommendation :-';
            app.ExplainabilityEvidencePanel = uipanel(app.UIFigure);
            app.ExplainabilityEvidencePanel.Title = 'Explainability — Grad-CAM Evidence';
            app.Image2 = uiimage(app.ExplainabilityEvidencePanel);
            app.LesionReferenceImage = uiimage(app.ExplainabilityEvidencePanel);
            app.LesionReferenceImage.ImageSource = fullfile(pathToMLAPP, 'lesion_reference.png');
            app.Label_2 = uilabel(app.ExplainabilityEvidencePanel);
            app.Label_2.Text = {'Model Attention Region — '; 'Grad-CAM'};
            app.StatusReadyLabel = uilabel(app.UIFigure);
            app.StatusReadyLabel.Text = 'Status: Ready';
            app.ViewCapacitySimulationButton = uibutton(app.UIFigure, 'push');
            app.ViewCapacitySimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewCapacitySimulationButtonPushed, true);
            app.ViewCapacitySimulationButton.Text = 'View Capacity Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DRISHTI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end